classdef ex1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        edge_detectButton  matlab.ui.control.Button
        detectButton       matlab.ui.control.Button
        gray_scaleButton   matlab.ui.control.Button
        loadButton         matlab.ui.control.Button
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
          original1 = "C:\Users\yusuk\matlab_prog\logo_csse.png";
          % read image
          im_1=imread(original1);
          imshow(im_1,'Parent',app.UIAxes);
        end

        % Callback function: not associated with a component
        function ImageClicked(app, event)
      
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
     
        end

        % Button pushed function: gray_scaleButton
        function gray_scaleButtonPushed(app, event)
          % get filename
          original2 = "C:\Users\yusuk\matlab_prog\logo_csse.png";
          % read image
          im_2=imread(original2);
          % compute image
          imgray = rgb2gray(im_2);
          BW = imbinarize(imgray);
          imshow(BW*255,'Parent',app.UIAxes_2);
          % plot images  
        end

        % Button pushed function: detectButton
        function detectButtonPushed(app, event)
          original3 = "C:\Users\yusuk\matlab_prog\logo_csse.png";
          % read image
          im_3=imread(original3); 
          txt = ocr(im_3);
          recognizedText = txt.Text;
          figure
          imshow(original3)
          text(600,150,recognizedText,BackgroundColor=[1 1 1]);
        end

        % Button pushed function: edge_detectButton
        function edge_detectButtonPushed(app, event)
          original4 = "C:\Users\yusuk\matlab_prog\logo_csse.png";
          % read image
          im_4=imread(original4);
          imgray = rgb2gray(im_4);
          BW = imbinarize(imgray);
          BW1 = edge(BW,'sobel');
          imshow(BW1,'Parent',app.UIAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'gray')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'edge')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.gray_scaleButton = uibutton(app.UIFigure, 'push');
            app.gray_scaleButton.ButtonPushedFcn = createCallbackFcn(app, @gray_scaleButtonPushed, true);
            app.gray_scaleButton.Text = 'gray_scale';
            app.detectButton = uibutton(app.UIFigure, 'push');
            app.detectButton.ButtonPushedFcn = createCallbackFcn(app, @detectButtonPushed, true);
            app.detectButton.Text = 'detect';
            app.edge_detectButton = uibutton(app.UIFigure, 'push');
            app.edge_detectButton.ButtonPushedFcn = createCallbackFcn(app, @edge_detectButtonPushed, true);
            app.edge_detectButton.Text = 'edge_detect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end